classdef DMCurrent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        COMEditField       matlab.ui.control.NumericEditField
        COMEditFieldLabel  matlab.ui.control.Label
        DisConnectButton   matlab.ui.control.Button
        ConnectButton      matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        s1 % Description
    end
    
    methods (Access = public)
        
        function results = readcurrent(app)
            try
                writeline(app.s1,'!chk;1');
                currentreply1=readline(app.s1);
            catch 
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
            comport=['COM',num2str(app.COMEditField.Value)];
            app.s1=serialport(comport,115200);
            app.s1.Timeout=1;
            disp('Connected!');
            app.ConnectButton.BackgroundColor=[0 1 0];
            app.DisConnectButton.BackgroundColor=[0.96 0.96 0.96];
            writeline(app.s1,'!idn');
            identity=readline(app.s1);
            disp(['Identity information:',identity]);
            catch
                disp('Connection error!')
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            end
        end

        % Button pushed function: DisConnectButton
        function DisConnectButtonPushed(app, event)
            try
                delete(app.s1);
                disp('Disconnected!');
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            catch
                disp('SerialPort does not exist in matlab!');
                app.ConnectButton.BackgroundColor=[0.96 0.96 0.96];
                app.DisConnectButton.BackgroundColor=[1 0 0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH2 Current v.s. T')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Current uA')
            zlabel(app.UIAxes, 'Z')
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisConnectButton = uibutton(app.UIFigure, 'push');
            app.DisConnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisConnectButtonPushed, true);
            app.DisConnectButton.Text = 'DisConnect';
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'numeric');
            app.COMEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DMCurrent

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end